classdef voiceProcess < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ButtonClear         matlab.ui.control.Button
        ButtonDesignFIlt    matlab.ui.control.Button
        HTMLHeader1         matlab.ui.control.HTML
        HTMLHeader2         matlab.ui.control.HTML
        HTMLHeader3         matlab.ui.control.HTML
        SpinnerRecordTime   matlab.ui.control.Spinner
        Label_8             matlab.ui.control.Label
        SpinnerPassAmp      matlab.ui.control.Spinner
        Label_7             matlab.ui.control.Label
        SpinnerStopAmp      matlab.ui.control.Spinner
        Label_6             matlab.ui.control.Label
        ButtonFilterFvtool  matlab.ui.control.Button
        LabelIsRecording    matlab.ui.control.Label
        LampIsRecording     matlab.ui.control.Lamp
        ButtonPlayFilt      matlab.ui.control.Button
        ButtonPlayOrigin    matlab.ui.control.Button
        ButtonSetFilter     matlab.ui.control.Button
        SpinnerPass         matlab.ui.control.Spinner
        Label_5             matlab.ui.control.Label
        SpinnerStop         matlab.ui.control.Spinner
        Label_4             matlab.ui.control.Label
        ButtonRecord        matlab.ui.control.Button
        AxesFilterFFTAngle  matlab.ui.control.UIAxes
        AxesFilterFFT       matlab.ui.control.UIAxes
        AxesFiltFFT         matlab.ui.control.UIAxes
        AxesOriginFFT       matlab.ui.control.UIAxes
        AxesFilter          matlab.ui.control.UIAxes
        AxesFilt            matlab.ui.control.UIAxes
        AxesOrigin          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        sampleRate = 44100; % 采样率
        time = 2; % 录音时长 秒
        t = 0:1/44100:(2-1/44100); % 时间范围
        audioData = (0:1/44100:(2-1/44100)) .* 0 % 录到的音频
        audioProcessed = (0:1/44100:(2-1/44100)) .* 0 % 处理后的音频
        % 设计数字滤波器
        fir = makeFilter(20,80,exp(-1/10),exp(-15/10),44100);
        %fir = designfilt('lowpassfir', ...
        %    'FilterOrder',20,'PassbandFrequency',80, ...
        %    'StopbandFrequency',100,'SampleRate',44100);
    end
    
    methods (Access = private)
      
        function [] = beep(app) %提示音
            t2=0:1/app.sampleRate:0.15;
            sound(sin(2*pi*1000*t2),app.sampleRate)
        end

        function fir = makeFilter(~,Fpass,Fstop,Dpass,Dstop,Fs)
        % All  Hz.
        % 生成滤波器
        [N,Wn,BETA,TYPE] = kaiserord([Fpass Fstop]/(Fs/2), [1 0], [Dstop Dpass]);
        b  = fir1(N, Wn, TYPE, kaiser(N+1, BETA), 'scale');
        fir = dfilt.dffir(b);
        end
        
        function [] = refreshPlot(app)
            % 处理
            app.audioProcessed  = filter(app.fir,app.audioData);

            % 绘图
            f=(0:length(app.audioData)-1)*app.sampleRate/length(app.audioData);
            plot(app.AxesOrigin,app.t,app.audioData)
            ylim(app.AxesOrigin,[-0.5,+0.5]);
            plot(app.AxesOriginFFT,f,abs(fft(app.audioData)))
            xlim(app.AxesOriginFFT,[0,2000]);
            plot(app.AxesFilt,app.t,app.audioProcessed)
            ylim(app.AxesFilt,[-0.5,+0.5]);
            plot(app.AxesFiltFFT,f,abs(fft(app.audioProcessed)))
            xlim(app.AxesFiltFFT,[0,2000]);

            [H,w] = freqz(app.fir,[],app.sampleRate);
            [h,t] = impz(app.fir,app.sampleRate);
            stem(app.AxesFilter,t,h);
            plot(app.AxesFilterFFT,w,abs(H));
            plot(app.AxesFilterFFTAngle,w,angle(H));

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ButtonRecord
        function ButtonRecordPushed(app, event)
            % UI
            app.LampIsRecording.Color = '#FF0000';
            app.LabelIsRecording.Text = '录音中';
            app.ButtonRecord.Text = '录音中';
            app.ButtonRecord.Enable = false;


            % 录制
            recObj = audiorecorder(app.sampleRate,16,1,-1);
            app.beep()

            recordblocking(recObj,app.time);

            app.beep()
            app.audioData = recObj.getaudiodata("double");

            % 刷新绘图和处理信号
            app.refreshPlot()

            % UI
            app.LampIsRecording.Color = '#00FF00';
            app.LabelIsRecording.Text = '空闲';
            app.ButtonRecord.Enable = true;
            app.ButtonRecord.Text = '录音';
        end

        % Button pushed function: ButtonPlayFilt
        function ButtonPlayFiltPushed(app, event)
            % UI
            app.LampIsRecording.Color = '#0000FF';
            app.LabelIsRecording.Text = '播放中';
            app.ButtonPlayFilt.Text = '播放中';
            app.ButtonPlayOrigin.Enable = false;
            app.ButtonPlayFilt.Enable = false;
            app.beep()
            

            player = audioplayer(app.audioProcessed,app.sampleRate);
            playblocking(player);
            
            % UI
            app.beep()
            app.LampIsRecording.Color = '#00FF00';
            app.LabelIsRecording.Text = '空闲';
            app.ButtonPlayFilt.Text = '播放';
            app.ButtonPlayFilt.Enable = true;
            app.ButtonPlayOrigin.Enable = true;
        end

        % Button pushed function: ButtonPlayOrigin
        function ButtonPlayOriginPushed(app, event)
            % UI
            app.LampIsRecording.Color = '#0000FF';
            app.LabelIsRecording.Text = '播放中';
            app.ButtonPlayOrigin.Text = '播放中';
            app.ButtonPlayOrigin.Enable = false;
            app.ButtonPlayFilt.Enable = false;
            app.beep()
           
            player = audioplayer(app.audioData,app.sampleRate);
            playblocking(player);
            
            
            % UI
            app.beep()
            app.LampIsRecording.Color = '#00FF00';
            app.LabelIsRecording.Text = '空闲';
            app.ButtonPlayOrigin.Text = '播放';
            app.ButtonPlayOrigin.Enable = true;
            app.ButtonPlayFilt.Enable = true;
            

        end

        % Button pushed function: ButtonSetFilter
        function ButtonSetFilterPushed(app, event)
            % 设计数字滤波器
            %app.fir = designfilt('lowpassfir', ...
            %'PassbandFrequency',app.SpinnerPass.Value,'StopbandFrequency',app.SpinnerStop.Value, ...
            %'PassbandRipple',app.SpinnerPassAmp.Value,'StopbandAttenuation',app.SpinnerStopAmp.Value, ...
            %'SampleRate',app.sampleRate,'DesignMethod','kaiserwin');
            
            Fs = app.sampleRate;
            Fpass = app.SpinnerPass.Value;
            Fstop = app.SpinnerStop.Value;
            Dpass = exp(-app.SpinnerPassAmp.Value/10);
            Dstop = exp(-app.SpinnerStopAmp.Value/10);


            app.fir = app.makeFilter(Fpass,Fstop,Dpass,Dstop,Fs);
            app.refreshPlot()
        end

        % Button pushed function: ButtonFilterFvtool
        function ButtonFilterFvtoolPushed(app, event)
            fvtool(app.fir)
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            app.refreshPlot()
        end

        % Value changed function: SpinnerRecordTime
        function SpinnerRecordTimeValueChanged(app, event)
            value = app.SpinnerRecordTime.Value;
            app.time = value;
            app.t = 0:1/app.sampleRate:(app.time-1/app.sampleRate); % 时间范围
        end

        % Button pushed function: ButtonDesignFIlt
        function ButtonDesignFIltPushed(app, event)
            app.fir = designfilt;
        end

        % Button pushed function: ButtonClear
        function ButtonClearPushed(app, event)
            app.audioData = app.audioData.*0;
            app.refreshPlot()
            app.ButtonClear.Visible = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '信号处理系统';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.AxesOrigin = uiaxes(app.UIFigure);
            title(app.AxesOrigin, '原信号波形')
            xlabel(app.AxesOrigin, '时间 t/s')
            ylabel(app.AxesOrigin, '幅度')
            zlabel(app.AxesOrigin, 'Z')
            app.AxesFilt = uiaxes(app.UIFigure);
            title(app.AxesFilt, '滤波后的信号')
            xlabel(app.AxesFilt, '时间 t/s')
            ylabel(app.AxesFilt, '幅度')
            zlabel(app.AxesFilt, 'Z')
            app.AxesFilter = uiaxes(app.UIFigure);
            title(app.AxesFilter, '滤波器冲激响应')
            xlabel(app.AxesFilter, '时间 t/s')
            ylabel(app.AxesFilter, '幅度')
            zlabel(app.AxesFilter, 'Z')
            app.AxesOriginFFT = uiaxes(app.UIFigure);
            title(app.AxesOriginFFT, '原信号频谱')
            xlabel(app.AxesOriginFFT, '频率 f/Hz')
            ylabel(app.AxesOriginFFT, '幅值')
            zlabel(app.AxesOriginFFT, 'Z')
            app.AxesFiltFFT = uiaxes(app.UIFigure);
            title(app.AxesFiltFFT, '滤波后的信号频域')
            xlabel(app.AxesFiltFFT, '频率 f/Hz')
            ylabel(app.AxesFiltFFT, '幅值')
            zlabel(app.AxesFiltFFT, 'Z')
            app.AxesFilterFFT = uiaxes(app.UIFigure);
            title(app.AxesFilterFFT, '滤波器幅频特性 |H|')
            xlabel(app.AxesFilterFFT, '频率 f/Hz')
            ylabel(app.AxesFilterFFT, '幅值')
            zlabel(app.AxesFilterFFT, 'Z')
            app.AxesFilterFFTAngle = uiaxes(app.UIFigure);
            title(app.AxesFilterFFTAngle, '滤波器相频特性')
            xlabel(app.AxesFilterFFTAngle, '频率 f/Hz')
            ylabel(app.AxesFilterFFTAngle, '相位')
            zlabel(app.AxesFilterFFTAngle, 'Z')
            app.ButtonRecord = uibutton(app.UIFigure, 'push');
            app.ButtonRecord.ButtonPushedFcn = createCallbackFcn(app, @ButtonRecordPushed, true);
            app.ButtonRecord.Icon = 'mic.svg';
            app.ButtonRecord.Text = '录音';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '阻带频率(Hz)';
            app.SpinnerStop = uispinner(app.UIFigure);
            app.SpinnerStop.Step = 10;
            app.SpinnerStop.Limits = [0 Inf];
            app.SpinnerStop.Value = 600;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '通带频率(Hz)';
            app.SpinnerPass = uispinner(app.UIFigure);
            app.SpinnerPass.Step = 10;
            app.SpinnerPass.Limits = [0 Inf];
            app.SpinnerPass.Value = 550;
            app.ButtonSetFilter = uibutton(app.UIFigure, 'push');
            app.ButtonSetFilter.ButtonPushedFcn = createCallbackFcn(app, @ButtonSetFilterPushed, true);
            app.ButtonSetFilter.Text = '设置滤波器';
            app.ButtonPlayOrigin = uibutton(app.UIFigure, 'push');
            app.ButtonPlayOrigin.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlayOriginPushed, true);
            app.ButtonPlayOrigin.Icon = 'play.svg';
            app.ButtonPlayOrigin.Text = '播放';
            app.ButtonPlayFilt = uibutton(app.UIFigure, 'push');
            app.ButtonPlayFilt.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlayFiltPushed, true);
            app.ButtonPlayFilt.Icon = 'play.svg';
            app.ButtonPlayFilt.Text = '播放';
            app.LampIsRecording = uilamp(app.UIFigure);
            app.LabelIsRecording = uilabel(app.UIFigure);
            app.LabelIsRecording.Text = '空闲';
            app.ButtonFilterFvtool = uibutton(app.UIFigure, 'push');
            app.ButtonFilterFvtool.ButtonPushedFcn = createCallbackFcn(app, @ButtonFilterFvtoolPushed, true);
            app.ButtonFilterFvtool.Text = '详细查看';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '阻带增益(dB)';
            app.SpinnerStopAmp = uispinner(app.UIFigure);
            app.SpinnerStopAmp.Value = 15;
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '通带增益(dB)';
            app.SpinnerPassAmp = uispinner(app.UIFigure);
            app.SpinnerPassAmp.Limits = [1 100];
            app.SpinnerPassAmp.Value = 1;
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '录音时长(秒)';
            app.SpinnerRecordTime = uispinner(app.UIFigure);
            app.SpinnerRecordTime.Step = 0.5;
            app.SpinnerRecordTime.Limits = [0.5 60];
            app.SpinnerRecordTime.ValueChangedFcn = createCallbackFcn(app, @SpinnerRecordTimeValueChanged, true);
            app.SpinnerRecordTime.Value = 5;
            app.HTMLHeader3 = uihtml(app.UIFigure);
            app.HTMLHeader3.HTMLSource = '<hr/><center>滤波结果</center>';
            app.HTMLHeader2 = uihtml(app.UIFigure);
            app.HTMLHeader2.HTMLSource = '<hr/><center>滤波器</center>';
            app.HTMLHeader1 = uihtml(app.UIFigure);
            app.HTMLHeader1.HTMLSource = '<center><h2> Matlab语音信号处理系统</h2><hr/><center>录音</center>';
            app.ButtonDesignFIlt = uibutton(app.UIFigure, 'push');
            app.ButtonDesignFIlt.ButtonPushedFcn = createCallbackFcn(app, @ButtonDesignFIltPushed, true);
            app.ButtonDesignFIlt.Text = '详细设计';
            app.ButtonClear = uibutton(app.UIFigure, 'push');
            app.ButtonClear.ButtonPushedFcn = createCallbackFcn(app, @ButtonClearPushed, true);
            app.ButtonClear.Text = '清零';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = voiceProcess

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end